classdef EZentropy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EZEntropyUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SingleMenu                      matlab.ui.container.Menu
        LoadbatchmultipleASCIIfilesMenu  matlab.ui.container.Menu
        WriteresultstofileMenu          matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        AboutEZEntropyMenu              matlab.ui.container.Menu
        SignalAxes                      matlab.ui.control.UIAxes
        Panel_Sub                       matlab.ui.container.Panel
        FilesLabel                      matlab.ui.control.Label
        FileNameText                    matlab.ui.control.Label
        FileNameListBox                 matlab.ui.control.ListBox
        Panel_En                        matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ResTable                        matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        StandardTab                     matlab.ui.container.Tab
        CommonPanel                     matlab.ui.container.Panel
        EmbeddingdimensionLabel         matlab.ui.control.Label
        EmbeddingdimensionEditField     matlab.ui.control.NumericEditField
        TimedelayLabel                  matlab.ui.control.Label
        TimedelayEditField              matlab.ui.control.NumericEditField
        ApEnSampEnFuzzyEnPanel          matlab.ui.container.Panel
        ThresholdvalueEditFieldLabel    matlab.ui.control.Label
        ThresholdvalueEditField         matlab.ui.control.NumericEditField
        SDLabel                         matlab.ui.control.Label
        DistEnPanel                     matlab.ui.container.Panel
        BinnumberEditFieldLabel         matlab.ui.control.Label
        BinnumberEditField              matlab.ui.control.NumericEditField
        CEPanel                         matlab.ui.container.Panel
        QuantificationlevelEditFieldLabel  matlab.ui.control.Label
        QuantificationlevelEditField    matlab.ui.control.NumericEditField
        SDEPanel                        matlab.ui.container.Panel
        QuantificationlevelEditField_2Label  matlab.ui.control.Label
        QuantificationlevelEditField_2  matlab.ui.control.NumericEditField
        logtransformCheckBox            matlab.ui.control.CheckBox
        AdvancedTab                     matlab.ui.container.Tab
        DataPanel                       matlab.ui.container.Panel
        EnableGapCheckBox               matlab.ui.control.CheckBox
        NormalizeLabel                  matlab.ui.control.Label
        NormalizeDropDown               matlab.ui.control.DropDown
        MemoryPanel                     matlab.ui.container.Panel
        TryWorkstationCheckBox          matlab.ui.control.CheckBox
        ShowImageCheckBox               matlab.ui.control.CheckBox
        WriteWhileAnalyzeCheckBox       matlab.ui.control.CheckBox
        AnalysisPanel                   matlab.ui.container.Panel
        MovingWindowCheckBox            matlab.ui.control.CheckBox
        WindowlengthEditFieldLabel      matlab.ui.control.Label
        WindowlengthEditField           matlab.ui.control.NumericEditField
        OverlapEditFieldLabel           matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        OverlapLabel                    matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        motifAxes                       matlab.ui.control.UIAxes
        ProbabilityofmotifsLabel        matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        mTab                            matlab.ui.container.Tab
        MapAxes                         matlab.ui.control.UIAxes
        m1Tab                           matlab.ui.container.Tab
        MapAxes1                        matlab.ui.control.UIAxes
        TabGroup2_2                     matlab.ui.container.TabGroup
        pdfTab                          matlab.ui.container.Tab
        pdfAxes                         matlab.ui.control.UIAxes
        cdfTab                          matlab.ui.container.Tab
        cdfAxes                         matlab.ui.control.UIAxes
        cdfAxes_zoom                    matlab.ui.control.UIAxes
        batchLabel                      matlab.ui.control.Label
        NextwindowButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        timeseries % a physiological time series object
        hts        % handle of time series plot
        
        Gap        % gap
        hgap       % handles of gap plot
        
        Seg        % segment
        hseg       % segment
        
        SigYRange  % signal axes yrange
        hwind      % moving window marker on SignalAxes
        
        file_noext % filename no extension
        file_path  % data path
        
        file_list
        seg_list
        gap_list
        
        % parameter group
        m
        tau
        r
        B
        
        % results
        ind_out  = 1
        outputfile
        
        % control
        batch = false
        WWA   = false
        
        % cache
        image_
        windnum_
    end
    
    properties (SetAccess = private, Dependent = true)
        dataready = false  % data ready
        buffer    = true   % buffer enough
    end
    
    methods
        function val = get.dataready(app)
            val = ~isempty(app.timeseries) || app.batch;
        end
        
        function val = get.buffer(app)
            if app.dataready && ~app.TryWorkstationCheckBox.Value
                if app.MovingWindowCheckBox.Value
                    val = app.WindowlengthEditField.Value <= 1e4;
                else
                    val = app.timeseries.Length <= 1e4;
                end
            else
                val = app.dataready;
            end
        end
    end
    
    methods (Access = private)
        function InitPlot(app)
            app.hts   = [];
            app.hgap  = [];
            app.hseg  = [];
            app.hwind = [];
            app.SignalAxes.NextPlot = 'replace';
            
            cla(app.MapAxes);
            cla(app.MapAxes1);
            cla(app.pdfAxes);
            cla(app.cdfAxes_zoom);
            cla(app.cdfAxes);
            cla(app.motifAxes);
            
            if ~app.batch
                app.ResTable.Data = [];
                app.ind_out = 1;
            end
            
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            position = CenterFig(1280, 720, 'pixels');
            app.EZEntropyUIFigure.Position = position;
            
            app.ResTable.ColumnFormat = {'char' [] [] [] [] [] [] [] [] []};
        end

        % Menu selected function: SingleMenu
        function SingleMenuSelected(app, event)
            app.batch = false;
            
            [filename, pathname] = uigetfile({'*.txt'}, 'Select a file ...');
            if ~filename
                return;
            end
            
            app.file_noext = filename(1:end-4);
            app.file_path  = pathname;
            
            app.Label.Text = 'Loading data ...';
            
            orig_data = load(fullfile(pathname, filename));
            app.timeseries = physiologicaltimeseries(orig_data);
            app.timeseries.Quality = ones(app.timeseries.Length, 1);
            
            app.Label.Text = 'Data loaded ...';
            
            InitPlot(app);
            app.hts = plot(app.SignalAxes, 1:app.timeseries.Length, app.timeseries.Data, 'k');
            app.SignalAxes.BackgroundColor = 'w';
            app.SignalAxes.Box = 'on';
            xlabel(app.SignalAxes, 'Point');
            app.SignalAxes.XLim     = [0 app.timeseries.Length];
            app.SigYRange           = app.SignalAxes.YLim;
            app.SignalAxes.NextPlot = 'add';
            
            if app.EnableGapCheckBox.Value
                EnableGapCheckBoxValueChanged(app, event);
            end
            
            app.FileNameText.Text = filename;
            
            app.FileNameListBox.Value = {};
            app.FileNameListBox.Items = {''};
            app.FileNameListBox.Visible = 'off';
            
            if app.MovingWindowCheckBox.Value == 1
                app.NextwindowButton.Visible = 'on';
            end
            
            app.Label.Text = 'Ready';
        end

        % Value changed function: EnableGapCheckBox
        function EnableGapCheckBoxValueChanged(app, event)
            if ~app.dataready
                return;
            end
            value = app.EnableGapCheckBox.Value;
            if value
                gappath = fullfile(app.file_path, 'Gap');
                if exist(gappath, 'dir') == 7
                    if exist(fullfile(gappath, [app.file_noext '.gap']), 'file') == 2
                        app.Gap = load(fullfile(gappath, [app.file_noext '.gap']));
                        app.Label.Text = 'Drawing gap(s) ...';
                        
                        for iG = 1:size(app.Gap, 1)
                            app.hgap(iG) = patch('Parent', app.SignalAxes, ...
                                'XData', [app.Gap(iG, 1), app.Gap(iG, 1), app.Gap(iG, 2), app.Gap(iG, 2)], ...
                                'YData', [app.SigYRange(1) app.SigYRange(2) app.SigYRange(2) app.SigYRange(1)], ...
                                'FaceColor', 'y', 'EdgeColor', 'y', 'FaceAlpha', .2);
                            
                            app.timeseries.Quality(app.Gap(iG, 1):app.Gap(iG, 2)) = 0;
                            
                            drawnow;
                        end
                        
                        child = app.SignalAxes.Children; child = [child(end); child(1:end-1)]; app.SignalAxes.Children = child;
                    end
                end
            else
                if ~isempty(app.hgap)
                    delete(app.hgap);
                    app.hgap = [];
                end
            end
            app.Label.Text = 'Ready';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if ~app.dataready
                return;
            end
            
            app.ResTable.Data = [];
            app.ind_out       = 1;
            
            if ~app.batch
                filenum = 1;
            else
                filenum = length(app.file_list);
            end
            
            app.ResTable.BackgroundColor = [1 1 1; .94 .94 .94];
            
            app.m   = app.EmbeddingdimensionEditField.Value;
            app.tau = app.TimedelayEditField.Value;
            app.r   = app.ThresholdvalueEditField.Value;
            app.B   = app.BinnumberEditField.Value;
            
            for iF = 1:filenum
                if app.batch
                    app.batchLabel.Text = ['Processing file #: ' num2str(iF) '/' num2str(filenum)];
                    app.FileNameListBox.Value = app.file_list(iF);
                    
                    % load data
                    filename = app.file_list{iF};
                    app.file_noext = filename(1:end-4);
                    
                    orig_data = load(fullfile(app.file_path, filename));
                    app.timeseries = physiologicaltimeseries(orig_data);
                    app.timeseries.Quality = ones(app.timeseries.Length, 1);
                    
                    app.Label.Text = 'Data loaded ...';
                    
                    InitPlot(app);
                    app.hts = plot(app.SignalAxes, 1:app.timeseries.Length, app.timeseries.Data, 'k');
                    app.SignalAxes.BackgroundColor = 'w';
                    app.SignalAxes.Box = 'on';
                    xlabel(app.SignalAxes, 'Point');
                    app.SignalAxes.XLim     = [0 app.timeseries.Length];
                    app.SigYRange           = app.SignalAxes.YLim;
                    app.SignalAxes.NextPlot = 'add';
                    
                    % gap
                    if ~isempty(app.gap_list{iF})
                        gappath = fullfile(app.file_path, 'Gap');
                        if exist(gappath, 'dir') == 7
                            if exist(fullfile(gappath, app.gap_list{iF}), 'file') == 2
                                app.Gap = load(fullfile(gappath, app.gap_list{iF}));
                                app.Label.Text = 'Drawing gap(s) ...';
                                
                                for iG = 1:size(app.Gap, 1)
                                    app.hgap(iG) = patch('Parent', app.SignalAxes, ...
                                        'XData', [app.Gap(iG, 1), app.Gap(iG, 1), app.Gap(iG, 2), app.Gap(iG, 2)], ...
                                        'YData', [app.SigYRange(1) app.SigYRange(2) app.SigYRange(2) app.SigYRange(1)], ...
                                        'FaceColor', 'y', 'EdgeColor', 'y', 'FaceAlpha', .2);
                                    
                                    app.timeseries.Quality(app.Gap(iG, 1):app.Gap(iG, 2)) = 0;
                                    
                                    drawnow;
                                end
                                
                                child = app.SignalAxes.Children; child = [child(end); child(1:end-1)]; app.SignalAxes.Children = child;
                            end
                        end
                    else
                        if ~isempty(app.hgap)
                            delete(app.hgap);
                            app.hgap = [];
                        end
                    end
                    
                    % seg
                    if ~isempty(app.seg_list{iF})
                        segpath = fullfile(app.file_path, 'Seg');
                        if exist(segpath, 'dir') == 7
                            if exist(fullfile(segpath, app.seg_list{iF}), 'file') == 2
                                app.Seg = load(fullfile(segpath, app.seg_list{iF}));
                                app.Label.Text = 'Drawing seg(s) ...';
                                
                                for iG = 1:size(app.Seg, 1)
                                    app.hseg(iG) = patch('Parent', app.SignalAxes, ...
                                        'XData', [app.Seg(iG, 1), app.Seg(iG, 1), app.Seg(iG, 2), app.Seg(iG, 2)], ...
                                        'YData', [app.SigYRange(1) app.SigYRange(2) app.SigYRange(2) app.SigYRange(1)], ...
                                        'FaceColor', 'g', 'EdgeColor', 'g', 'FaceAlpha', .2);
                                    
                                    app.timeseries.Quality(app.Seg(iG, 1):app.Seg(iG, 2)) = 2;
                                    
                                    drawnow;
                                end
                                
                                child = app.SignalAxes.Children; child = [child(end); child(1:end-1)]; app.SignalAxes.Children = child;
                            end
                        end
                    else
                        if ~isempty(app.hseg)
                            delete(app.hseg);
                            app.hseg = [];
                        end
                    end
                end
                
                if app.MovingWindowCheckBox.Value
                    StartInd = 1:(app.WindowlengthEditField.Value - floor(app.OverlapEditField.Value * app.WindowlengthEditField.Value)):app.timeseries.Length;
                    StartInd(StartInd >= app.timeseries.Length) = [];
                    EndInd   = StartInd + app.WindowlengthEditField.Value - 1;
                    EndInd   = min(EndInd, app.timeseries.Length);
                else
                    StartInd = 1;
                    EndInd   = app.timeseries.Length;
                end
                
                % cache
                if ~app.batch
                    app.windnum_         = length(StartInd);
                    app.image_.WindXData = zeros(length(StartInd), 5);
                    app.image_.schebm    = {};
                    app.image_.scheba    = {};
                    app.image_.cdf_m     = {};
                    app.image_.cdf_a     = {};
                    app.image_.pdf       = {};
                    app.image_.motif     = {};
                    app.image_.motiflab  = {};
                end
                
                for iW = 1:length(StartInd)
                    app.Label.Text = ['Calculating ' repmat('.', 1, mod(iW, 4)) ' Window: ' num2str(iW) '/' num2str(length(StartInd))];
                    subts = getsamples(app.timeseries, StartInd(iW):EndInd(iW));
                    
                    y = subts.Data;
                    y(subts.Quality == 0) = NaN;
                    
                    % data normalize
                    switch app.NormalizeDropDown.Value
                        case 'zscore'
                            y = (y - nanmean(y)) ./ nanstd(y);
                        case 'minmax'
                            y = (y - min(y)) ./ (max(y) - min(y));
                            app.r = app.ThresholdvalueEditField.Value * nanstd(y);
                    end
                    
                    if isempty(app.hwind)
                        app.hwind = plot(app.SignalAxes, [StartInd(iW) StartInd(iW) EndInd(iW) EndInd(iW) StartInd(iW)], ...
                            [app.SigYRange(1) app.SigYRange(2) app.SigYRange(2) app.SigYRange(1) app.SigYRange(1)], 'b', 'LineWidth', 2);
                        if ~app.batch
                            app.image_.WindXData(iW, :) = app.hwind.XData;
                        end
                    else
                        app.hwind.XData = [StartInd(iW) StartInd(iW) EndInd(iW) EndInd(iW) StartInd(iW)];
                        if ~app.batch
                            app.image_.WindXData(iW, :) = app.hwind.XData;
                        end
                    end
                    
                    % state space reconstruction
                    N = subts.Length;
                    indm = hankel(1:N-app.m*app.tau, N-app.m*app.tau:N-app.tau);
                    inda = hankel(1:N-app.m*app.tau, N-app.m*app.tau:N);
                    indm = indm(:, 1:app.tau:end);
                    inda = inda(:, 1:app.tau:end);
                    ym   = y(indm);
                    ya   = y(inda);
                    
                    if app.buffer
                        chebm = pdist(ym, 'chebychev'); schebm = squareform(chebm);
                        cheba = pdist(ya, 'chebychev'); scheba = squareform(cheba);
                        
                        % show distance map
                        if app.ShowImageCheckBox.Value
                            imagesc(app.MapAxes,  schebm); colormap(app.MapAxes,  'gray'); app.MapAxes.YDir  = 'normal'; app.MapAxes.BackgroundColor  = 'w';
                            imagesc(app.MapAxes1, scheba); colormap(app.MapAxes1, 'gray'); app.MapAxes1.YDir = 'normal'; app.MapAxes1.BackgroundColor = 'w';
                            app.MapAxes.Box  = 'on'; app.MapAxes.XLim  = [1 size(schebm, 1)]; app.MapAxes.YLim  = [1 size(schebm, 2)];
                            app.MapAxes1.Box = 'on'; app.MapAxes1.XLim = [1 size(scheba, 1)]; app.MapAxes1.YLim = [1 size(scheba, 2)];
                            
                            drawnow;
                            
                            % cache image
                            if ~app.batch
                                app.image_.schebm{iW} = schebm;
                                app.image_.scheba{iW} = scheba;
                            end
                            
                            % show cdf
                            try
                                nchebm = chebm(~isnan(chebm)); ncheba = cheba(~isnan(cheba));
                                [cdf_x_m, foccur] = unique(sort(nchebm), 'first'); d_num_m = diff([foccur; length(nchebm)+1]); d_cfreq_m = cumsum(d_num_m ./ length(chebm));
                                [cdf_x_a, foccur] = unique(sort(ncheba), 'first'); d_num_a = diff([foccur; length(ncheba)+1]); d_cfreq_a = cumsum(d_num_a ./ length(cheba));
                                plot(app.cdfAxes, cdf_x_m, d_cfreq_m, 'Color', [101 76 255]./255, 'LineWidth', 2); app.cdfAxes.NextPlot = 'add';
                                plot(app.cdfAxes, cdf_x_a, d_cfreq_a, 'Color', [229 25 50]./255, 'LineWidth', 2);
                                plot(app.cdfAxes, [app.r app.r], [0 1], 'k--');
                                app.cdfAxes.NextPlot = 'replace';
                                app.cdfAxes.BackgroundColor = 'w'; app.cdfAxes.XLim  = [0 max(max(chebm), max(cheba))]; app.cdfAxes.YLim = [min(min(d_cfreq_m), min(d_cfreq_a)), 1];
                                app.cdfAxes.TickLength = [.025 .025]; app.cdfAxes.TickDir = 'out';
                                hl = legend(app.cdfAxes, {'m', 'm+1', 'threshold'}, 'Location', 'northoutside', 'Orientation', 'horizontal'); hl.Box = 'off';
                                
                                plot(app.cdfAxes_zoom, cdf_x_m, d_cfreq_m, 'Color', [101 76 255]./255, 'LineWidth', 2); app.cdfAxes_zoom.NextPlot = 'add';
                                plot(app.cdfAxes_zoom, cdf_x_a, d_cfreq_a, 'Color', [229 25 50]./255, 'LineWidth', 2);
                                plot(app.cdfAxes_zoom, [app.r app.r], [0 1], 'k--');
                                app.cdfAxes_zoom.NextPlot = 'replace';
                                app.cdfAxes_zoom.BackgroundColor = 'w'; app.cdfAxes_zoom.XLim  = [0 2*app.r]; app.cdfAxes_zoom.YLim = [min(min(d_cfreq_m), min(d_cfreq_a)), 1.05*max(max(d_cfreq_m(cdf_x_m<=app.r)), max(d_cfreq_a(cdf_x_a<=app.r)))];
                                app.cdfAxes_zoom.TickLength = [.025 .025]; app.cdfAxes_zoom.TickDir = 'out'; app.cdfAxes_zoom.Color = 'none'; app.cdfAxes_zoom.FontSize = 10;
                                
                                % cache image
                                if ~app.batch
                                    app.image_.cdf_m{iW} = [cdf_x_m(:) d_cfreq_m(:)];
                                    app.image_.cdf_a{iW} = [cdf_x_a(:) d_cfreq_a(:)];
                                end
                            catch
                                cla(app.cdfAxes);
                                cla(app.cdfAxes_zoom);
                                
                                if ~app.batch
                                    app.image_.cdf_m{iW} = nan;
                                    app.image_.cdf_a{iW} = nan;
                                end
                            end
                        end
                        
                        % calculate
                        % ApEn
                        cm = sum(schebm < app.r, 2) ./ (N-app.m*app.tau);
                        ca = sum(scheba < app.r, 2) ./ (N-app.m*app.tau);
                        apen_val = sum(-log(ca ./ cm)) ./ (N-app.m*app.tau);
                        
                        % SampEn
                        cm = sum(chebm <= app.r)*2 / (size(ym, 1)*(size(ym, 1)-1));
                        ca = sum(cheba <= app.r)*2 / (size(ya, 1)*(size(ya, 1)-1));
                        sampen_val = -log(sum(ca) / sum(cm));
                        
                        % FuzzyEn
                        cm = nansum(exp(-log(2).*(chebm./app.r).^2))*2 / (size(ym, 1)*(size(ym, 1)-1));
                        ca = nansum(exp(-log(2).*(cheba./app.r).^2))*2 / (size(ya, 1)*(size(ya, 1)-1));
                        fuzzyen_val = -log(sum(ca) / sum(cm));
                        
                        % DistEn
                        if ~isnan(max(cheba))
                            xi   = linspace(0, max(cheba), app.B);
                            num  = hist(cheba, xi);
                            freq = num./sum(num);
                            disten_val = -sum(freq.*log2(freq+eps)) ./ log2(app.B);
                            
                            if app.ShowImageCheckBox.Value
                                % show pdf
                                stem(app.pdfAxes, xi, freq, 'Color', [255 127 0]./255, 'Marker', 'none');
                                app.pdfAxes.NextPlot = 'replace';
                                app.pdfAxes.BackgroundColor = 'w'; app.pdfAxes.XLim  = [0 max(chebm)]; app.pdfAxes.YLim = [0, max(freq)];
                                app.pdfAxes.TickLength = [.025 .025]; app.pdfAxes.TickDir = 'out'; app.pdfAxes.Box = 'off';
                                
                                % cache image
                                if ~app.batch
                                    app.image_.pdf{iW} = [xi(:) freq(:)];
                                end
                            end
                        else
                            disten_val = nan;
                            if app.ShowImageCheckBox.Value
                                cla(app.pdfAxes);
                                
                                if ~app.batch
                                    app.image_.pdf{iW} = nan;
                                end
                            end
                        end
                        
                        % PermEn
                        ya(any(isnan(ya), 2), :) = [];
                        if ~isempty(ya)
                            [~, sortpat] = sort(ya, 2);
                            [~, motif]   = sort(sortpat, 2);
                            codemotif    = motif * (app.m+1).^(0:app.m)';
                            [scodemotif, ind] = sort(codemotif);
                            [~, foccur]  = unique(scodemotif, 'first');
                            motif_num    = diff([foccur; length(codemotif)+1]);
                            motif_freq   = motif_num / length(codemotif);
                            permen_val   = -sum(motif_freq .* log2(motif_freq));
                            
                            if app.ShowImageCheckBox.Value
                                % show motif plot
                                unimotif = motif(ind(foccur), :);
                                bar(app.motifAxes, 1:size(unimotif, 1), motif_freq, 'BarWidth', .8, 'FaceColor', [178 255 140]./255, 'EdgeColor', [101 76 255]./255);
                                xright = max(size(unimotif, 1), 20);
                                app.motifAxes.XLim = [.5 xright+.5]; app.motifAxes.Box = 'off'; app.motifAxes.TickLength = [.015 .025]; app.motifAxes.TickDir = 'out';
                                app.motifAxes.XTick = 1:size(unimotif, 1); app.motifAxes.XTickLabel = num2str(unimotif); app.motifAxes.XTickLabelRotation = 30;
                                
                                % cache image
                                if ~app.batch
                                    app.image_.motif{iW}    = [(1:size(unimotif, 1))', motif_freq(:)];
                                    app.image_.motiflab{iW} = num2str(unimotif);
                                end
                            end
                        else
                            permen_val   = nan;
                            if app.ShowImageCheckBox.Value
                                cla(app.motifAxes);
                                
                                if ~app.batch
                                    app.image_.motif{iW}    = nan;
                                    app.image_.motiflab{iW} = nan;
                                end
                            end
                        end
                        
                        % CE
                        y_coarse = floor((y - min(y)) ./ (max(y) - min(y)) .* app.QuantificationlevelEditField.Value);
                        y_coarse(y_coarse == app.QuantificationlevelEditField.Value) = app.QuantificationlevelEditField.Value - 1;
                        y_patm = y_coarse(indm); code_patm = y_patm * app.QuantificationlevelEditField.Value.^(0:app.m-1)';
                        y_pata = y_coarse(inda); code_pata = y_pata * app.QuantificationlevelEditField.Value.^(0:app.m)';
                        code_patm(any(isnan(code_patm), 2), :) = []; code_pata(any(isnan(code_pata), 2), :) = []; y_coarse(isnan(y_coarse)) = [];
                        [~, foccur] = unique(sort(code_patm), 'first'); patm_num = diff([foccur; length(code_patm)+1]); patm_freq = patm_num ./ length(code_patm);
                        [~, foccur] = unique(sort(code_pata), 'first'); pata_num = diff([foccur; length(code_pata)+1]); pata_freq = pata_num ./ length(code_pata); perc = sum(pata_num == 1) / length(pata_num);
                        [~, foccur] = unique(sort(y_coarse), 'first');  pat1_num = diff([foccur; length(y_coarse)+1]);  pat1_freq = pat1_num ./ length(y_coarse);
                        sem = -sum(patm_freq .* log2(patm_freq)); sea = -sum(pata_freq .* log2(pata_freq)); se1 = -sum(pat1_freq .* log2(pat1_freq));
                        ce_val = sea - sem + se1*perc;
                        
                        % SDE
                        y_ = subts.Data;
                        if app.logtransformCheckBox.Value
                            y_ = log10(y_ - min(y_) + 1);
                        end
                        y_coarse = floor((y_ - min(y_)) ./ (max(y_) - min(y_)) .* app.QuantificationlevelEditField_2.Value);
                        y_coarse(y_coarse == app.QuantificationlevelEditField_2.Value) = app.QuantificationlevelEditField_2.Value - 1;
                        y_a = y_coarse(inda);
                        if app.buffer
                            cheb_a = pdist(y_a, 'chebychev');
                            
                            p_ = zeros(app.QuantificationlevelEditField_2.Value, 1);
                            for ip = 1:length(cheb_a)
                                p_(cheb_a(ip)+1) = p_(cheb_a(ip)+1) + 1;
                            end
                            p_ = p_ ./ sum(p_);
                            
                            sde_val = -sum(p_.*log2(p_+eps)) ./ log2(length(p_));
                        else
                            sde_val = nan;
                        end
                        
                        % show table
                        if app.ind_out == 1 % refresh
                            app.ResTable.Data = [];
                        end
                        app.ResTable.Data{app.ind_out, 1} = app.file_noext;
                        app.ResTable.Data{app.ind_out, 2} = iW;
                        app.ResTable.Data{app.ind_out, 3} = apen_val;
                        app.ResTable.Data{app.ind_out, 4} = sampen_val;
                        app.ResTable.Data{app.ind_out, 5} = fuzzyen_val;
                        app.ResTable.Data{app.ind_out, 6} = disten_val;
                        app.ResTable.Data{app.ind_out, 7} = permen_val;
                        app.ResTable.Data{app.ind_out, 8} = ce_val;
                        app.ResTable.Data{app.ind_out, 9} = sde_val;
                        app.ResTable.Data{app.ind_out, 10} = sum(~isnan(y))/length(y)*100;
                        
                        app.ind_out = app.ind_out + 1;
                        
                        if app.WWA
                            fid = fopen(app.outputfile, 'a');
                            fprintf(fid, '%s,%d,%f,%f,%f,%f,%f,%f,%f,%f\n', app.file_noext, iW, apen_val, sampen_val, fuzzyen_val, disten_val, permen_val, ce_val, sde_val, sum(~isnan(y))/length(y)*100);
                            fclose(fid);
                            
                            if (app.ind_out > length(StartInd)) && app.batch
                                app.ind_out = 1;
                            end
                        end
                        drawnow;
                    end
                end
            end
            app.batchLabel.Text = '';
            app.Label.Text = 'Ready';
            app.ind_out    = app.ind_out - 1;
        end

        % Value changed function: MovingWindowCheckBox
        function MovingWindowCheckBoxValueChanged(app, event)
            value = app.MovingWindowCheckBox.Value;
            if value
                app.WindowlengthEditField.Enable = 'on';
                app.OverlapEditField.Enable      = 'on';
                app.OverlapLabel.Enable          = 'on';
                
                if ~app.batch
                    app.NextwindowButton.Visible = 'on';
                end
            else
                app.WindowlengthEditField.Enable = 'off';
                app.OverlapEditField.Enable      = 'off';
                app.OverlapLabel.Enable          = 'off';
                
                app.NextwindowButton.Visible     = 'off';
            end
        end

        % Button pushed function: NextwindowButton
        function NextwindowButtonPushed(app, event)
            if ~app.dataready || isempty(app.ResTable.Data)
                return;
            end
            switch app.Label.Text
                case 'Ready'
                    app.ind_out = mod(app.ind_out, app.windnum_) + 1;
                    app.hwind.XData = app.image_.WindXData(app.ind_out, :);
                    drawnow;
                    
                    % replay distance map
                    imagesc(app.MapAxes,  app.image_.schebm{app.ind_out}); colormap(app.MapAxes,  'gray'); app.MapAxes.YDir  = 'normal'; app.MapAxes.BackgroundColor  = 'w';
                    imagesc(app.MapAxes1, app.image_.scheba{app.ind_out}); colormap(app.MapAxes1, 'gray'); app.MapAxes1.YDir = 'normal'; app.MapAxes1.BackgroundColor = 'w';
                    app.MapAxes.Box  = 'on'; app.MapAxes.XLim  = [1 size(app.image_.schebm{app.ind_out}, 1)]; app.MapAxes.YLim  = [1 size(app.image_.schebm{app.ind_out}, 2)];
                    app.MapAxes1.Box = 'on'; app.MapAxes1.XLim = [1 size(app.image_.scheba{app.ind_out}, 1)]; app.MapAxes1.YLim = [1 size(app.image_.scheba{app.ind_out}, 2)];
                    drawnow;
                    
                    % replay cdf
                    if ~isnan(app.image_.cdf_m{app.ind_out})
                        cdf_x_m = app.image_.cdf_m{app.ind_out}(:, 1); d_cfreq_m = app.image_.cdf_m{app.ind_out}(:, 2);
                        cdf_x_a = app.image_.cdf_a{app.ind_out}(:, 1); d_cfreq_a = app.image_.cdf_a{app.ind_out}(:, 2);
                        plot(app.cdfAxes, cdf_x_m, d_cfreq_m, 'Color', [101 76 255]./255, 'LineWidth', 2); app.cdfAxes.NextPlot = 'add';
                        plot(app.cdfAxes, cdf_x_a, d_cfreq_a, 'Color', [229 25 50]./255, 'LineWidth', 2);
                        plot(app.cdfAxes, [app.r app.r], [0 1], 'k--');
                        app.cdfAxes.NextPlot = 'replace';
                        app.cdfAxes.BackgroundColor = 'w'; app.cdfAxes.XLim  = [0 max(max(cdf_x_m), max(cdf_x_a))]; app.cdfAxes.YLim = [min(min(d_cfreq_m), min(d_cfreq_a)), 1];
                        app.cdfAxes.TickLength = [.025 .025]; app.cdfAxes.TickDir = 'out';
                        hl = legend(app.cdfAxes, {'m', 'm+1', 'threshold'}, 'Location', 'northoutside', 'Orientation', 'horizontal'); hl.Box = 'off';
                        
                        plot(app.cdfAxes_zoom, cdf_x_m, d_cfreq_m, 'Color', [101 76 255]./255, 'LineWidth', 2); app.cdfAxes_zoom.NextPlot = 'add';
                        plot(app.cdfAxes_zoom, cdf_x_a, d_cfreq_a, 'Color', [229 25 50]./255, 'LineWidth', 2);
                        plot(app.cdfAxes_zoom, [app.r app.r], [0 1], 'k--');
                        app.cdfAxes_zoom.NextPlot = 'replace';
                        app.cdfAxes_zoom.BackgroundColor = 'w'; app.cdfAxes_zoom.XLim  = [0 2*app.r]; app.cdfAxes_zoom.YLim = [min(min(d_cfreq_m), min(d_cfreq_a)), 1.05*max(max(d_cfreq_m(cdf_x_m<=app.r)), max(d_cfreq_a(cdf_x_a<=app.r)))];
                        app.cdfAxes_zoom.TickLength = [.025 .025]; app.cdfAxes_zoom.TickDir = 'out'; app.cdfAxes_zoom.Color = 'none'; app.cdfAxes_zoom.FontSize = 10;
                    else
                        cla(app.cdfAxes);
                        cla(app.cdfAxes_zoom);
                    end
                    drawnow;
                    
                    % replay pdf
                    if ~isnan(app.image_.pdf{app.ind_out})
                        xi = app.image_.pdf{app.ind_out}(:, 1); freq = app.image_.pdf{app.ind_out}(:, 2);
                        stem(app.pdfAxes, xi, freq, 'Color', [255 127 0]./255, 'Marker', 'none');
                        app.pdfAxes.NextPlot = 'replace';
                        app.pdfAxes.BackgroundColor = 'w'; app.pdfAxes.XLim  = [0 max(xi)]; app.pdfAxes.YLim = [0, max(freq)];
                        app.pdfAxes.TickLength = [.025 .025]; app.pdfAxes.TickDir = 'out'; app.pdfAxes.Box = 'off';
                    else
                        cla(app.pdfAxes);
                    end
                    drawnow;
                    
                    % replay motif
                    if ~isnan(app.image_.motif{app.ind_out})
                        x = app.image_.motif{app.ind_out}(:, 1); motif_freq = app.image_.motif{app.ind_out}(:, 2);
                        bar(app.motifAxes, x, motif_freq, 'BarWidth', .8, 'FaceColor', [178 255 140]./255, 'EdgeColor', [101 76 255]./255);
                        xright = max(x(end), 20);
                        app.motifAxes.XLim = [.5 xright+.5]; app.motifAxes.Box = 'off'; app.motifAxes.TickLength = [.015 .025]; app.motifAxes.TickDir = 'out';
                        app.motifAxes.XTick = 1:x(end); app.motifAxes.XTickLabel = app.image_.motiflab{app.ind_out}; app.motifAxes.XTickLabelRotation = 30;
                    else
                        cla(app.motifAxes);
                    end
                    drawnow;
                    
                    % highlight table row
                    tablebg = [1 1 1; .94 .94 .94];
                    tablebg = repmat(tablebg, ceil(app.windnum_/2), 1);
                    tablebg = tablebg(1:app.windnum_, :);
                    tablebg(app.ind_out, :) = 1 - .3*(1 - [0 0 1]);
                    app.ResTable.BackgroundColor = tablebg;
            end
        end

        % Menu selected function: LoadbatchmultipleASCIIfilesMenu
        function LoadbatchmultipleASCIIfilesMenuSelected(app, event)
            called = importwizard;
            app.Label.Text = 'Waiting for importing data ...';
            uiwait(called.wizardFigure)
            if called.imported
                app.Label.Text = [num2str(length(called.FileList)) ' files imported.'];
                
                app.file_path  = called.DataPath;
                app.file_list  = called.FileList;
                app.seg_list   = called.SegList;
                app.gap_list   = called.GapList;
                
                app.batch      = true;
                app.NextwindowButton.Visible = 'off';
                app.FileNameText.Text        = '';
                
                app.ResTable.Data = [];
                app.ind_out       = 1;
                
                app.FileNameListBox.Visible = 'on';
                app.FileNameListBox.Items = app.file_list;
                app.FileNameListBox.Value = {};
            else
                app.Label.Text = 'No data imported. Ready for any tasks.';
            end
            called.delete;
        end

        % Menu selected function: WriteresultstofileMenu
        function WriteresultstofileMenuSelected(app, event)
            if strcmp(app.Label.Text, 'Ready') && ~isempty(app.ResTable.Data)
                Res = cell2table(app.ResTable.Data, 'VariableNames', app.ResTable.ColumnName);
                app.Label.Text = 'Save as ...';
                [filename, pathname] = uiputfile('*.txt', 'Save as ...');
                writetable(Res, fullfile(pathname, filename));
                app.Label.Text = 'Ready';
            end
        end

        % Menu selected function: AboutEZEntropyMenu
        function AboutEZEntropyMenuSelected(app, event)
            AboutEZ;
        end

        % Value changed function: WriteWhileAnalyzeCheckBox
        function WriteWhileAnalyzeCheckBoxValueChanged(app, event)
            value = app.WriteWhileAnalyzeCheckBox.Value;
            
            if value
                app.Label.Text = 'Set output file ...';
                [filename, pathname] = uiputfile('*.txt', 'Save as ...');
                
                if isequal(filename, 0) || isequal(pathname, 0)
                    app.Label.Text = 'User''s cancelled Write While Analyze. Ready for any tasks.';
                    app.WriteWhileAnalyzeCheckBox.Value = 0;
                else
                    app.outputfile = fullfile(pathname, filename);
                    
                    if exist(app.outputfile, 'file') == 2
                        app.Label.Text = 'Add results to existing file. Ready ...';
                    else
                        fid = fopen(app.outputfile, 'w');
                        for iC = 1:length(app.ResTable.ColumnName)
                            fprintf(fid, '%s', app.ResTable.ColumnName{iC, :});
                            if iC ~= length(app.ResTable.ColumnName)
                                fprintf(fid, ',');
                            else
                                fprintf(fid, '\n');
                            end
                        end
                        fclose(fid);
                        
                        app.Label.Text = 'Ready ...';
                    end
                    
                    app.WriteresultstofileMenu.Enable = 'off';
                    app.WWA = true;
                end
            else
                app.WriteresultstofileMenu.Enable = 'on';
                app.WWA = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EZEntropyUIFigure = uifigure('Visible', 'off');
            app.EZEntropyUIFigure.Name = 'EZ Entropy';
            app.FileMenu = uimenu(app.EZEntropyUIFigure);
            app.FileMenu.Text = '&File';
            app.SingleMenu = uimenu(app.FileMenu);
            app.SingleMenu.MenuSelectedFcn = createCallbackFcn(app, @SingleMenuSelected, true);
            app.SingleMenu.Accelerator = 'S';
            app.SingleMenu.Text = 'Load (a single column ASCII file)';
            app.LoadbatchmultipleASCIIfilesMenu = uimenu(app.FileMenu);
            app.LoadbatchmultipleASCIIfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadbatchmultipleASCIIfilesMenuSelected, true);
            app.LoadbatchmultipleASCIIfilesMenu.Accelerator = 'B';
            app.LoadbatchmultipleASCIIfilesMenu.Text = 'Load (batch multiple ASCII files)';
            app.WriteresultstofileMenu = uimenu(app.FileMenu);
            app.WriteresultstofileMenu.MenuSelectedFcn = createCallbackFcn(app, @WriteresultstofileMenuSelected, true);
            app.WriteresultstofileMenu.Separator = 'on';
            app.WriteresultstofileMenu.Accelerator = 'W';
            app.WriteresultstofileMenu.Text = 'Write results to file';
            app.HelpMenu = uimenu(app.EZEntropyUIFigure);
            app.HelpMenu.Text = '&Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Accelerator = 'D';
            app.DocumentationMenu.Text = 'Documentation';
            app.AboutEZEntropyMenu = uimenu(app.HelpMenu);
            app.AboutEZEntropyMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutEZEntropyMenuSelected, true);
            app.AboutEZEntropyMenu.Separator = 'on';
            app.AboutEZEntropyMenu.Accelerator = 'A';
            app.AboutEZEntropyMenu.Text = 'About EZ Entropy';
            app.SignalAxes = uiaxes(app.EZEntropyUIFigure);
            xlabel(app.SignalAxes, 'Sample points')
            ylabel(app.SignalAxes, 'Amplitude')
            app.Panel_Sub = uipanel(app.EZEntropyUIFigure);
            app.FilesLabel = uilabel(app.Panel_Sub);
            app.FilesLabel.Text = 'File(s):';
            app.FileNameText = uilabel(app.Panel_Sub);
            app.FileNameText.Text = '';
            app.FileNameListBox = uilistbox(app.Panel_Sub);
            app.FileNameListBox.Items = {};
            app.FileNameListBox.Visible = 'off';
            app.FileNameListBox.Value = {};
            app.Panel_En = uipanel(app.EZEntropyUIFigure);
            app.Label = uilabel(app.Panel_En);
            app.Label.Text = 'Ready';
            app.ResTable = uitable(app.Panel_En);
            app.ResTable.ColumnName = {'Filename'; 'WindowNum'; 'ApEn'; 'SampEn'; 'FuzzyEn'; 'DistEn'; 'PermEn'; 'CE'; 'SDE'; 'ValidPercent'};
            app.ResTable.RowName = {};
            app.TabGroup = uitabgroup(app.Panel_En);
            app.StandardTab = uitab(app.TabGroup);
            app.StandardTab.Title = 'Standard';
            app.CommonPanel = uipanel(app.StandardTab);
            app.CommonPanel.Title = 'Common';
            app.EmbeddingdimensionLabel = uilabel(app.CommonPanel);
            app.EmbeddingdimensionLabel.Text = 'Embedding dimension:';
            app.EmbeddingdimensionEditField = uieditfield(app.CommonPanel, 'numeric');
            app.EmbeddingdimensionEditField.Limits = [1 Inf];
            app.EmbeddingdimensionEditField.ValueDisplayFormat = '%.0f';
            app.EmbeddingdimensionEditField.Value = 2;
            app.TimedelayLabel = uilabel(app.CommonPanel);
            app.TimedelayLabel.Text = 'Time delay:';
            app.TimedelayEditField = uieditfield(app.CommonPanel, 'numeric');
            app.TimedelayEditField.Limits = [1 Inf];
            app.TimedelayEditField.ValueDisplayFormat = '%.0f';
            app.TimedelayEditField.Value = 1;
            app.ApEnSampEnFuzzyEnPanel = uipanel(app.StandardTab);
            app.ApEnSampEnFuzzyEnPanel.Title = 'ApEn, SampEn, FuzzyEn';
            app.ThresholdvalueEditFieldLabel = uilabel(app.ApEnSampEnFuzzyEnPanel);
            app.ThresholdvalueEditFieldLabel.Text = 'Threshold value:';
            app.ThresholdvalueEditField = uieditfield(app.ApEnSampEnFuzzyEnPanel, 'numeric');
            app.ThresholdvalueEditField.Limits = [0 Inf];
            app.ThresholdvalueEditField.Value = 0.2;
            app.SDLabel = uilabel(app.ApEnSampEnFuzzyEnPanel);
            app.SDLabel.Text = '×SD';
            app.DistEnPanel = uipanel(app.StandardTab);
            app.DistEnPanel.Title = 'DistEn';
            app.BinnumberEditFieldLabel = uilabel(app.DistEnPanel);
            app.BinnumberEditFieldLabel.Text = 'Bin number:';
            app.BinnumberEditField = uieditfield(app.DistEnPanel, 'numeric');
            app.BinnumberEditField.Limits = [2 Inf];
            app.BinnumberEditField.ValueDisplayFormat = '%.0f';
            app.BinnumberEditField.Value = 256;
            app.CEPanel = uipanel(app.StandardTab);
            app.CEPanel.Title = 'CE';
            app.QuantificationlevelEditFieldLabel = uilabel(app.CEPanel);
            app.QuantificationlevelEditFieldLabel.Text = 'Quantification level:';
            app.QuantificationlevelEditField = uieditfield(app.CEPanel, 'numeric');
            app.QuantificationlevelEditField.Limits = [2 Inf];
            app.QuantificationlevelEditField.ValueDisplayFormat = '%.0f';
            app.QuantificationlevelEditField.Value = 6;
            app.SDEPanel = uipanel(app.StandardTab);
            app.SDEPanel.Title = 'SDE';
            app.QuantificationlevelEditField_2Label = uilabel(app.SDEPanel);
            app.QuantificationlevelEditField_2Label.Text = 'Quantification level:';
            app.QuantificationlevelEditField_2 = uieditfield(app.SDEPanel, 'numeric');
            app.QuantificationlevelEditField_2.Limits = [1 Inf];
            app.QuantificationlevelEditField_2.ValueDisplayFormat = '%.0f';
            app.QuantificationlevelEditField_2.Value = 10;
            app.logtransformCheckBox = uicheckbox(app.SDEPanel);
            app.logtransformCheckBox.Text = 'log transform';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.DataPanel = uipanel(app.AdvancedTab);
            app.DataPanel.Title = 'Data';
            app.EnableGapCheckBox = uicheckbox(app.DataPanel);
            app.EnableGapCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableGapCheckBoxValueChanged, true);
            app.EnableGapCheckBox.Text = 'Enable Gap';
            app.EnableGapCheckBox.Value = true;
            app.NormalizeLabel = uilabel(app.DataPanel);
            app.NormalizeLabel.Text = 'Normalize:';
            app.NormalizeDropDown = uidropdown(app.DataPanel);
            app.NormalizeDropDown.Items = {'zscore', 'minmax'};
            app.NormalizeDropDown.Value = 'zscore';
            app.MemoryPanel = uipanel(app.AdvancedTab);
            app.MemoryPanel.Title = 'Memory';
            app.TryWorkstationCheckBox = uicheckbox(app.MemoryPanel);
            app.TryWorkstationCheckBox.Text = 'Try Workstation';
            app.ShowImageCheckBox = uicheckbox(app.MemoryPanel);
            app.ShowImageCheckBox.Text = 'Show Supporting Images';
            app.ShowImageCheckBox.Value = true;
            app.WriteWhileAnalyzeCheckBox = uicheckbox(app.MemoryPanel);
            app.WriteWhileAnalyzeCheckBox.ValueChangedFcn = createCallbackFcn(app, @WriteWhileAnalyzeCheckBoxValueChanged, true);
            app.WriteWhileAnalyzeCheckBox.Text = 'Write While Analyze';
            app.AnalysisPanel = uipanel(app.AdvancedTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.MovingWindowCheckBox = uicheckbox(app.AnalysisPanel);
            app.MovingWindowCheckBox.ValueChangedFcn = createCallbackFcn(app, @MovingWindowCheckBoxValueChanged, true);
            app.MovingWindowCheckBox.Text = 'Moving Window';
            app.WindowlengthEditFieldLabel = uilabel(app.AnalysisPanel);
            app.WindowlengthEditFieldLabel.Enable = 'off';
            app.WindowlengthEditFieldLabel.Text = 'Window length:';
            app.WindowlengthEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.WindowlengthEditField.Limits = [10 Inf];
            app.WindowlengthEditField.ValueDisplayFormat = '%.0f';
            app.WindowlengthEditField.Enable = 'off';
            app.WindowlengthEditField.Value = 100;
            app.OverlapEditFieldLabel = uilabel(app.AnalysisPanel);
            app.OverlapEditFieldLabel.Enable = 'off';
            app.OverlapEditFieldLabel.Text = 'Overlap:';
            app.OverlapEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.OverlapEditField.Limits = [0 99];
            app.OverlapEditField.ValueDisplayFormat = '%.0f';
            app.OverlapEditField.Enable = 'off';
            app.OverlapLabel = uilabel(app.AnalysisPanel);
            app.OverlapLabel.Enable = 'off';
            app.OverlapLabel.Text = '%';
            app.ApplyButton = uibutton(app.Panel_En, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.motifAxes = uiaxes(app.Panel_En);
            xlabel(app.motifAxes, 'motif')
            app.ProbabilityofmotifsLabel = uilabel(app.Panel_En);
            app.ProbabilityofmotifsLabel.Text = 'Probability of motifs';
            app.TabGroup2 = uitabgroup(app.Panel_En);
            app.mTab = uitab(app.TabGroup2);
            app.mTab.Title = 'm';
            app.MapAxes = uiaxes(app.mTab);
            app.MapAxes.XTickLabel = '';
            app.MapAxes.YTickLabel = '';
            app.m1Tab = uitab(app.TabGroup2);
            app.m1Tab.Title = 'm+1';
            app.MapAxes1 = uiaxes(app.m1Tab);
            app.MapAxes1.XTickLabel = '';
            app.MapAxes1.YTickLabel = '';
            app.TabGroup2_2 = uitabgroup(app.Panel_En);
            app.pdfTab = uitab(app.TabGroup2_2);
            app.pdfTab.Title = 'pdf';
            app.pdfAxes = uiaxes(app.pdfTab);
            app.cdfTab = uitab(app.TabGroup2_2);
            app.cdfTab.Title = 'cdf';
            app.cdfAxes = uiaxes(app.cdfTab);
            app.cdfAxes_zoom = uiaxes(app.cdfTab);
            app.batchLabel = uilabel(app.Panel_En);
            app.batchLabel.Text = '';
            app.NextwindowButton = uibutton(app.EZEntropyUIFigure, 'push');
            app.NextwindowButton.ButtonPushedFcn = createCallbackFcn(app, @NextwindowButtonPushed, true);
            app.NextwindowButton.Visible = 'off';
            app.NextwindowButton.Text = '▷';
            % Show the figure after all components are created
            app.EZEntropyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EZentropy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EZEntropyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EZEntropyUIFigure)
        end
    end
end